classdef ATMUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Cashier                       matlab.ui.Figure
        TransferButton                matlab.ui.control.Button
        WithdrawButton                matlab.ui.control.Button
        DepositButton                 matlab.ui.control.Button
        ReturnBackButton              matlab.ui.control.Button
        CheckBalanceButton            matlab.ui.control.Button
        LoginButton                   matlab.ui.control.Button
        FinishButton                  matlab.ui.control.Button
        InsertCardButton              matlab.ui.control.Button
        AccountEditFieldLabel         matlab.ui.control.Label
        AccountEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel        matlab.ui.control.Label
        PasswordEditField             matlab.ui.control.EditField
        TextArea                      matlab.ui.control.TextArea
        MoneyLabel                    matlab.ui.control.Label
        Money                         matlab.ui.control.NumericEditField
        FromEditFieldLabel            matlab.ui.control.Label
        FromEditField                 matlab.ui.control.NumericEditField
        ToEditFieldLabel              matlab.ui.control.Label
        ToEditField                   matlab.ui.control.NumericEditField
        AmountEditFieldLabel          matlab.ui.control.Label
        AmountEditField               matlab.ui.control.NumericEditField
        ConfirmButton_2               matlab.ui.control.Button
        WithdrawEditFieldLabel        matlab.ui.control.Label
        WithdrawEditField             matlab.ui.control.NumericEditField
        ConfirmButton_3               matlab.ui.control.Button
        DepositEditFieldLabel         matlab.ui.control.Label
        DepositEditField              matlab.ui.control.NumericEditField
        ConfirmButton_4               matlab.ui.control.Button
        ClosingAccountEditFieldLabel  matlab.ui.control.Label
        ClosingAccountEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        DB % Description
        Open_first
        Cur_ind
        To_ind
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.TextArea.Value = "Welcome to SHTU Bank";
        end

        % Button pushed function: InsertCardButton
        function InsertCardButtonPushed(app, event)
            app.InsertCardButton.Visible = 0;
            app.FinishButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.AccountEditFieldLabel.Position = [236,286,51,22];
            app.AccountEditField.Position = [304,286,100,22];
            app.PasswordEditFieldLabel.Position = [236,253,59,22];
            app.PasswordEditField.Position = [304,253,100,22];
            app.LoginButton.Position = [270,216,100,22];
            app.AccountEditFieldLabel.Visible = 1;
            app.AccountEditField.Visible = 1;
            app.PasswordEditFieldLabel.Visible = 1;
            app.PasswordEditField.Visible = 1;
            app.LoginButton.Visible = 1;
            app.TextArea.Value = "Please Insert Your Card and Fill In Password";
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            acc = app.AccountEditField.Value;
            pas = app.PasswordEditField.Value;
            if isempty(acc) 
                app.TextArea.Value = "Please Insert";
                return;
            end
            if isempty(pas) 
                app.TextArea.Value = "Please Insert";
                return;
            end
            base = importfile(app.DB.addr);
            acclist = base.Account;
            ind = find(acclist == str2num(acc));
            app.Cur_ind = ind;
            if base.Password(ind) == str2num(pas)
                flag = 1;
            else
                flag = 0;
            end           
            if flag == 1
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
            else
                app.TextArea.Value = "Wrong Password";
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            closereq;            
        end

        % Button pushed function: ReturnBackButton
        function ReturnBackButtonPushed(app, event)
            if app.LoginButton.Visible == "on"
                app.InsertCardButton.Visible = 1;
                app.FinishButton.Position = [255,165,131,40];
                app.FinishButton.Visible = 1;
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.AccountEditField.Value = "";
                app.PasswordEditField.Value = "";
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.ReturnBackButton.Visible = 0;
                app.TextArea.Value = "Welcome to SHTU Bank";
               
                
            elseif app.TextArea.Value == "Money On Your Account"
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.ReturnBackButton.Visible = 1;
                app.Money.Visible = 0;
                app.MoneyLabel.Visible = 0;
                app.Money.Value = 0;
                
            elseif app.ToEditField.Visible == "on"
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.ReturnBackButton.Visible = 1;
                app.Money.Visible = 0;
                app.MoneyLabel.Visible = 0;
                app.Money.Value = 0;
                app.FromEditField.Visible = 0;
                app.FromEditFieldLabel.Visible = 0;
                app.ToEditField.Visible = 0;
                app.ToEditFieldLabel.Visible = 0;
                app.ToEditField.Value = 0;
                app.AmountEditField.Visible = 0;
                app.AmountEditField.Value = 0;
                app.AmountEditFieldLabel.Visible = 0;
                app.ConfirmButton_2.Visible = 0;
                
            elseif app.DepositEditField.Visible == "on"
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.ReturnBackButton.Visible = 1;
                app.Money.Visible = 0;
                app.MoneyLabel.Visible = 0;
                app.Money.Value = 0;
                app.DepositEditFieldLabel.Visible = 0;
                app.DepositEditField.Visible = 0;
                app.ConfirmButton_4.Visible = 0;
                
            elseif app.WithdrawEditField.Visible == "on"
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.ReturnBackButton.Visible = 1;
                app.Money.Visible = 0;
                app.MoneyLabel.Visible = 0;
                app.Money.Value = 0;
                app.WithdrawEditField.Visible = 0;
                app.WithdrawEditFieldLabel.Visible = 0;
                app.ConfirmButton_3.Visible = 0;
                
            elseif app.CheckBalanceButton.Visible == "on"
                app.AccountEditFieldLabel.Visible = 1;
                app.AccountEditField.Visible = 1;
                app.PasswordEditFieldLabel.Visible = 1;
                app.PasswordEditField.Visible = 1;
                app.AccountEditField.Value = "";
                app.PasswordEditField.Value = "";
                app.LoginButton.Visible = 1;
                app.FinishButton.Position = [255,165,131,40];
                app.CheckBalanceButton.Visible = 0;
                app.TransferButton.Visible = 0;
                app.WithdrawButton.Visible = 0;
                app.DepositButton.Visible = 0;
                app.TextArea.Value = "Please Insert Your Card and Fill In Password";
            end
        end

        % Button pushed function: CheckBalanceButton
        function CheckBalanceButtonPushed(app, event)
            app.AccountEditFieldLabel.Visible = 0;
            app.AccountEditField.Visible = 0;
            app.PasswordEditFieldLabel.Visible = 0;
            app.PasswordEditField.Visible = 0;
            app.AccountEditField.Value = "";
            app.PasswordEditField.Value = "";
            app.LoginButton.Visible = 0;
            app.FinishButton.Position = [255,165,131,40];
            app.CheckBalanceButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.Money.Visible = 1;
            app.MoneyLabel.Visible = 1;
            app.TextArea.Value = "Money On Your Account";
            base = importfile(app.DB.addr);
            app.Money.Value = base.Balance(app.Cur_ind);
        end

        % Button pushed function: TransferButton
        function TransferButtonPushed(app, event)
            app.AccountEditFieldLabel.Visible = 0;
            app.AccountEditField.Visible = 0;
            app.PasswordEditFieldLabel.Visible = 0;
            app.PasswordEditField.Visible = 0;
            app.AccountEditField.Value = "";
            app.PasswordEditField.Value = "";
            app.LoginButton.Visible = 0;
            app.FinishButton.Position = [255,165,131,40];
            app.CheckBalanceButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.TextArea.Value = "Transfer";
            app.FromEditField.Visible = 1;
            app.FromEditFieldLabel.Visible = 1;
            app.ToEditField.Visible = 1;
            app.ToEditFieldLabel.Visible = 1;
            app.AmountEditField.Visible = 1;
            app.AmountEditFieldLabel.Visible = 1;
            app.ConfirmButton_2.Visible = 1;
            
            base = importfile(app.DB.addr);
            app.FromEditField.Value = base.Account(app.Cur_ind);            
        end

        % Button pushed function: WithdrawButton
        function WithdrawButtonPushed(app, event)
            app.AccountEditFieldLabel.Visible = 0;
            app.AccountEditField.Visible = 0;
            app.PasswordEditFieldLabel.Visible = 0;
            app.PasswordEditField.Visible = 0;
            app.AccountEditField.Value = "";
            app.PasswordEditField.Value = "";
            app.LoginButton.Visible = 0;
            app.FinishButton.Position = [255,165,131,40];
            app.CheckBalanceButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.TextArea.Value = "Withdraw";
            app.WithdrawEditField.Visible = 1;
            app.WithdrawEditFieldLabel.Visible = 1;
            app.ConfirmButton_3.Visible = 1;
        end

        % Button pushed function: DepositButton
        function DepositButtonPushed(app, event)
            app.AccountEditFieldLabel.Visible = 0;
            app.AccountEditField.Visible = 0;
            app.PasswordEditFieldLabel.Visible = 0;
            app.PasswordEditField.Visible = 0;
            app.AccountEditField.Value = "";
            app.PasswordEditField.Value = "";
            app.LoginButton.Visible = 0;
            app.FinishButton.Position = [255,165,131,40];
            app.CheckBalanceButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.TextArea.Value = "Deposit";
            app.DepositEditFieldLabel.Visible = 1;
            app.DepositEditField.Visible = 1;
            app.ConfirmButton_4.Visible = 1;
        end

        % Button pushed function: ConfirmButton_2
        function ConfirmButton_2Pushed(app, event)
            accto = app.ToEditField.Value;
            base = importfile(app.DB.addr);
            acclist = base.Account;
            if app.TextArea.Value == "Please Confirm Again"
                base.Balance(app.To_ind) = base.Balance(app.To_ind) + app.AmountEditField.Value;
                base.Balance(app.Cur_ind) = base.Balance(app.Cur_ind) - app.AmountEditField.Value;
                app.DB.rewrite(base);
                app.TextArea.Value = "Successful!";
                app.AmountEditField.Value = 0;
                app.ToEditField.Value = 0;
            elseif find(acclist == accto)
                app.To_ind = find(acclist == accto);
                if app.AmountEditField.Value <= 0
                    app.TextArea.Value = "Invalid Amount";
                else
                    balan = base(app.Cur_ind,3);
                    if (balan >= app.AmountEditField.Value)
                        app.TextArea.Value = "Please Confirm Again";
                    else
                        app.TextArea.Value = "Not Enough Balance";
                    end
                end
            else
                app.TextArea.Value = "No Such Account";
            end
            
        end

        % Button pushed function: ConfirmButton_3
        function ConfirmButton_3Pushed(app, event)
            base = importfile(app.DB.addr);
            if app.TextArea.Value == "Please Confirm Again"
                base.Balance(app.Cur_ind) = base.Balance(app.Cur_ind) - app.WithdrawEditField.Value;
                app.DB.rewrite(base);
                app.TextArea.Value = "Successful!";
                app.WithdrawEditField.Value = 0;
            elseif app.WithdrawEditField.Value > 0
                balan = base(app.Cur_ind,3);
                if (balan >= app.WithdrawEditField.Value)
                    app.TextArea.Value = "Please Confirm Again";
                else
                    app.TextArea.Value = "Not Enough Balance";
                end
            else
                app.TextArea.Value = "Invalid Amount";
            end
       
        end

        % Button pushed function: ConfirmButton_4
        function ConfirmButton_4Pushed(app, event)
            base = importfile(app.DB.addr);
            if app.TextArea.Value == "Please Confirm Again"
                base.Balance(app.Cur_ind) = base.Balance(app.Cur_ind) + app.DepositEditField.Value;
                app.DB.rewrite(base);
                app.TextArea.Value = "Successful!";
                app.DepositEditField.Value = 0;
            elseif app.DepositEditField.Value > 0
                app.TextArea.Value = "Please Confirm Again";
            else
                app.TextArea.Value = "Invalid Amount";
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Cashier = uifigure;
            app.Cashier.Name = 'ATM';
            app.TransferButton = uibutton(app.Cashier, 'push');
            app.TransferButton.ButtonPushedFcn = createCallbackFcn(app, @TransferButtonPushed, true);
            app.TransferButton.Visible = 'off';
            app.TransferButton.Text = 'Transfer';
            app.WithdrawButton = uibutton(app.Cashier, 'push');
            app.WithdrawButton.ButtonPushedFcn = createCallbackFcn(app, @WithdrawButtonPushed, true);
            app.WithdrawButton.Visible = 'off';
            app.WithdrawButton.Text = 'Withdraw';
            app.DepositButton = uibutton(app.Cashier, 'push');
            app.DepositButton.ButtonPushedFcn = createCallbackFcn(app, @DepositButtonPushed, true);
            app.DepositButton.Visible = 'off';
            app.DepositButton.Text = 'Deposit';
            app.ReturnBackButton = uibutton(app.Cashier, 'push');
            app.ReturnBackButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnBackButtonPushed, true);
            app.ReturnBackButton.Visible = 'off';
            app.ReturnBackButton.Text = 'Return Back';
            app.CheckBalanceButton = uibutton(app.Cashier, 'push');
            app.CheckBalanceButton.ButtonPushedFcn = createCallbackFcn(app, @CheckBalanceButtonPushed, true);
            app.CheckBalanceButton.Visible = 'off';
            app.CheckBalanceButton.Text = 'Check Balance';
            app.LoginButton = uibutton(app.Cashier, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Visible = 'off';
            app.LoginButton.Text = 'Login';
            app.FinishButton = uibutton(app.Cashier, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Tooltip = {''; ''};
            app.FinishButton.Text = 'Finish';
            app.InsertCardButton = uibutton(app.Cashier, 'push');
            app.InsertCardButton.ButtonPushedFcn = createCallbackFcn(app, @InsertCardButtonPushed, true);
            app.InsertCardButton.Text = 'Insert Card';
            app.AccountEditFieldLabel = uilabel(app.Cashier);
            app.AccountEditFieldLabel.Visible = 'off';
            app.AccountEditFieldLabel.Text = 'Account';
            app.AccountEditField = uieditfield(app.Cashier, 'text');
            app.AccountEditField.Visible = 'off';
            app.PasswordEditFieldLabel = uilabel(app.Cashier);
            app.PasswordEditFieldLabel.Visible = 'off';
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.Cashier, 'text');
            app.PasswordEditField.Visible = 'off';
            app.TextArea = uitextarea(app.Cashier);
            app.TextArea.Editable = 'off';
            app.MoneyLabel = uilabel(app.Cashier);
            app.MoneyLabel.Visible = 'off';
            app.MoneyLabel.Text = 'Money: ¥';
            app.Money = uieditfield(app.Cashier, 'numeric');
            app.Money.Limits = [0 Inf];
            app.Money.ValueDisplayFormat = '%.2f';
            app.Money.Editable = 'off';
            app.Money.Visible = 'off';
            app.FromEditFieldLabel = uilabel(app.Cashier);
            app.FromEditFieldLabel.Visible = 'off';
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.Cashier, 'numeric');
            app.FromEditField.Limits = [0 Inf];
            app.FromEditField.ValueDisplayFormat = '%.0f';
            app.FromEditField.Editable = 'off';
            app.FromEditField.Visible = 'off';
            app.ToEditFieldLabel = uilabel(app.Cashier);
            app.ToEditFieldLabel.Visible = 'off';
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.Cashier, 'numeric');
            app.ToEditField.Limits = [0 999999];
            app.ToEditField.ValueDisplayFormat = '%.0f';
            app.ToEditField.Visible = 'off';
            app.AmountEditFieldLabel = uilabel(app.Cashier);
            app.AmountEditFieldLabel.Visible = 'off';
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.Cashier, 'numeric');
            app.AmountEditField.Limits = [0 Inf];
            app.AmountEditField.ValueDisplayFormat = '%.2f';
            app.AmountEditField.Visible = 'off';
            app.ConfirmButton_2 = uibutton(app.Cashier, 'push');
            app.ConfirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_2Pushed, true);
            app.ConfirmButton_2.Visible = 'off';
            app.ConfirmButton_2.Text = 'Confirm';
            app.WithdrawEditFieldLabel = uilabel(app.Cashier);
            app.WithdrawEditFieldLabel.Visible = 'off';
            app.WithdrawEditFieldLabel.Text = 'Withdraw  ¥';
            app.WithdrawEditField = uieditfield(app.Cashier, 'numeric');
            app.WithdrawEditField.Limits = [0 Inf];
            app.WithdrawEditField.ValueDisplayFormat = '%.2f';
            app.WithdrawEditField.Visible = 'off';
            app.ConfirmButton_3 = uibutton(app.Cashier, 'push');
            app.ConfirmButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_3Pushed, true);
            app.ConfirmButton_3.Visible = 'off';
            app.ConfirmButton_3.Text = 'Confirm';
            app.DepositEditFieldLabel = uilabel(app.Cashier);
            app.DepositEditFieldLabel.Visible = 'off';
            app.DepositEditFieldLabel.Text = 'Deposit  ¥';
            app.DepositEditField = uieditfield(app.Cashier, 'numeric');
            app.DepositEditField.Limits = [0 Inf];
            app.DepositEditField.ValueDisplayFormat = '%.2f';
            app.DepositEditField.Visible = 'off';
            app.ConfirmButton_4 = uibutton(app.Cashier, 'push');
            app.ConfirmButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_4Pushed, true);
            app.ConfirmButton_4.Visible = 'off';
            app.ConfirmButton_4.Text = 'Confirm';
            app.ClosingAccountEditFieldLabel = uilabel(app.Cashier);
            app.ClosingAccountEditFieldLabel.Visible = 'off';
            app.ClosingAccountEditFieldLabel.Text = 'Closing Account';
            app.ClosingAccountEditField = uieditfield(app.Cashier, 'numeric');
            app.ClosingAccountEditField.Limits = [0 999999];
            app.ClosingAccountEditField.ValueDisplayFormat = '%.0f';
            app.ClosingAccountEditField.Editable = 'off';
            app.ClosingAccountEditField.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ATMUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Cashier)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Cashier)
        end
    end
end