classdef CashierUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Cashier                       matlab.ui.Figure
        CurrentNOEditFieldLabel       matlab.ui.control.Label
        CurrentNOEditField            matlab.ui.control.NumericEditField
        TransferButton                matlab.ui.control.Button
        WithdrawButton                matlab.ui.control.Button
        DepositButton                 matlab.ui.control.Button
        CloseAccountButton            matlab.ui.control.Button
        ConfirmButton                 matlab.ui.control.Button
        ReturnBackButton              matlab.ui.control.Button
        OpenAccountButton             matlab.ui.control.Button
        CheckBalanceButton            matlab.ui.control.Button
        LoginButton                   matlab.ui.control.Button
        FinishButton                  matlab.ui.control.Button
        InsertCardButton              matlab.ui.control.Button
        AccountEditFieldLabel         matlab.ui.control.Label
        AccountEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel        matlab.ui.control.Label
        PasswordEditField             matlab.ui.control.EditField
        YourAccountEditFieldLabel     matlab.ui.control.Label
        YourAccountEditField          matlab.ui.control.EditField
        SetPasswordEditFieldLabel     matlab.ui.control.Label
        SetPasswordEditField          matlab.ui.control.EditField
        TextArea                      matlab.ui.control.TextArea
        MoneyLabel                    matlab.ui.control.Label
        Money                         matlab.ui.control.NumericEditField
        FromEditFieldLabel            matlab.ui.control.Label
        FromEditField                 matlab.ui.control.NumericEditField
        ToEditFieldLabel              matlab.ui.control.Label
        ToEditField                   matlab.ui.control.NumericEditField
        AmountEditFieldLabel          matlab.ui.control.Label
        AmountEditField               matlab.ui.control.NumericEditField
        ConfirmButton_2               matlab.ui.control.Button
        WithdrawEditFieldLabel        matlab.ui.control.Label
        WithdrawEditField             matlab.ui.control.NumericEditField
        ConfirmButton_3               matlab.ui.control.Button
        DepositEditFieldLabel         matlab.ui.control.Label
        DepositEditField              matlab.ui.control.NumericEditField
        ConfirmButton_4               matlab.ui.control.Button
        YesButton                     matlab.ui.control.Button
        ClosingAccountEditFieldLabel  matlab.ui.control.Label
        ClosingAccountEditField       matlab.ui.control.NumericEditField
        TradingRecordButton           matlab.ui.control.Button
        TextArea_2                    matlab.ui.control.TextArea
        YourNameEditFieldLabel        matlab.ui.control.Label
        YourNameEditField             matlab.ui.control.EditField
        YourNameLabel                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        DB % Description
        Open_first
        Cur_ind
        To_ind
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.TextArea.Value = "Welcome to SHTU Bank";
        end

        % Button pushed function: InsertCardButton
        function InsertCardButtonPushed(app, event)
            app.OpenAccountButton.Visible = 0;
            app.InsertCardButton.Visible = 0;
            app.FinishButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.AccountEditFieldLabel.Position = [236,286,51,22];
            app.AccountEditField.Position = [304,286,100,22];
            app.PasswordEditFieldLabel.Position = [236,253,59,22];
            app.PasswordEditField.Position = [304,253,100,22];
            app.LoginButton.Position = [270,216,100,22];
            app.AccountEditFieldLabel.Visible = 1;
            app.AccountEditField.Visible = 1;
            app.PasswordEditFieldLabel.Visible = 1;
            app.PasswordEditField.Visible = 1;
            app.LoginButton.Visible = 1;
            app.TextArea.Value = "Please Insert Your Card and Fill In Password";
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            acc = app.AccountEditField.Value;
            pas = app.PasswordEditField.Value;
            base = importfile(app.DB.addr);
            acclist = base.Account;
            ind = find(acclist == str2num(acc));
            app.Cur_ind = ind;
            if base.Password(ind) == str2num(pas)
                flag = 1;
            else
                flag = 0;
            end           
            if flag == 1
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.CloseAccountButton.Visible = 1;
                app.TradingRecordButton.Visible = 1;
                app.YourNameLabel.Text = "Hello, " + base.Name(app.Cur_ind);
                app.YourNameLabel.Visible = 1;
            else
                app.TextArea.Value = "Wrong Password";
            end
        end

        % Button pushed function: OpenAccountButton
        function OpenAccountButtonPushed(app, event)
            app.OpenAccountButton.Visible = 0;
            app.InsertCardButton.Visible = 0;
            app.FinishButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.YourNameEditFieldLabel.Position= [220,298,78,22];
            app.YourNameEditField.Position = [307,298,100,22];
            app.YourAccountEditFieldLabel.Position = [220,271,78,22];
            app.YourAccountEditField.Position = [307,271,100,22];
            app.SetPasswordEditFieldLabel.Position = [219,244,80,22];
            app.SetPasswordEditField.Position = [308,244,100,22];
            app.ConfirmButton.Position = [274,209,100,22];
            app.YourNameEditField.Visible = 1;
            app.YourNameEditFieldLabel.Visible = 1;
            app.YourAccountEditField.Visible = 1;
            app.YourAccountEditFieldLabel.Visible = 1;
            app.SetPasswordEditField.Visible = 1;
            app.SetPasswordEditFieldLabel.Visible = 1;
            app.ConfirmButton.Visible = 1;
            app.SetPasswordEditField.Value = "";
            app.SetPasswordEditField.Editable = "on";
            app.YourNameEditField.Value = "";
            app.YourNameEditField.Editable = "on";
            app.ConfirmButton.Visible = "on";
            app.TextArea.Value = "Please Set Your 6-bit Digit Password";
            account = num2str(app.DB.generate_account());       
            app.YourAccountEditField.Value = account;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            if app.TextArea.Value == "Please Confirm Again"
                if app.SetPasswordEditField.Value ~= app.Open_first
                    app.TextArea.Value = "Mismatch! Please Start From Beginning";
                    app.Open_first = "";
                else
                    re = {app.YourNameEditField.Value,app.YourAccountEditField.Value,app.Open_first,0};
                    app.DB.write_end(re);
                    app.TextArea.Value = "Open Account Success";
                    app.SetPasswordEditField.Value = "******";
                    app.YourNameEditField.Editable = "off";
                    app.SetPasswordEditField.Editable = "off";
                    app.ConfirmButton.Visible = "off";
                end
            elseif length(app.SetPasswordEditField.Value) ~= 6
                app.TextArea.Value = "Wrong! Please Set Your 6-bit Digit Password";
            elseif ~app.YourNameEditField.Value 
                app.TextArea.Value = "Wrong! Please Set Your Name";
            elseif app.YourNameEditField.Value == "null"
                app.TextArea.Value = "Sorry, You Can't Use This Name";
            else
                iset = isstrprop(app.SetPasswordEditField.Value,'digit');
                if iset
                    app.Open_first = app.SetPasswordEditField.Value;
                    app.TextArea.Value = "Please Confirm Again";
                else
                    app.TextArea.Value = "Wrong! Please Set Your 6-bit Digit Password";
                end
            end
            
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            if ~isempty(app.DB.queue)
                app.DB.outqueue();
                if isempty(app.DB.queue)
                    closereq;
                else
                    app.CurrentNOEditField.Value = app.DB.queue(1);
                end
            else
                closereq;
            end
            
        end

        % Button pushed function: ReturnBackButton
        function ReturnBackButtonPushed(app, event)
            if app.LoginButton.Visible == "on"
                app.OpenAccountButton.Visible = 1;
                app.InsertCardButton.Visible = 1;
                app.FinishButton.Position = [255,165,131,40];
                app.FinishButton.Visible = 1;
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.AccountEditField.Value = "";
                app.PasswordEditField.Value = "";
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.ReturnBackButton.Visible = 0;
                app.TextArea.Value = "Welcome to SHTU Bank";
                
            elseif app.YourAccountEditFieldLabel.Visible == "on"
                app.OpenAccountButton.Visible = 1;
                app.InsertCardButton.Visible = 1;
                app.FinishButton.Position = [255,165,131,40];
                app.FinishButton.Visible = 1;
                app.YourNameEditField.Visible = 0;
                app.YourNameEditFieldLabel.Visible = 0;
                app.YourAccountEditField.Visible = 0;
                app.YourAccountEditFieldLabel.Visible = 0;
                app.SetPasswordEditField.Visible = 0;
                app.SetPasswordEditFieldLabel.Visible = 0;
                app.ConfirmButton.Visible = 0;
                app.ReturnBackButton.Visible = 0;
                app.TextArea.Value = "Welcome to SHTU Bank";
                
            elseif app.TextArea.Value == "Money On Your Account"
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.CloseAccountButton.Visible = 1;
                app.TradingRecordButton.Visible = 1;
                app.ReturnBackButton.Visible = 1;
                app.Money.Visible = 0;
                app.MoneyLabel.Visible = 0;
                app.Money.Value = 0;
                
            elseif app.ToEditField.Visible == "on"
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.CloseAccountButton.Visible = 1;
                app.TradingRecordButton.Visible = 1;
                app.ReturnBackButton.Visible = 1;
                app.Money.Visible = 0;
                app.MoneyLabel.Visible = 0;
                app.Money.Value = 0;
                app.FromEditField.Visible = 0;
                app.FromEditFieldLabel.Visible = 0;
                app.ToEditField.Visible = 0;
                app.ToEditFieldLabel.Visible = 0;
                app.ToEditField.Value = 0;
                app.AmountEditField.Visible = 0;
                app.AmountEditFieldLabel.Visible = 0;
                app.AmountEditField.Value = 0;
                app.ConfirmButton_2.Visible = 0;
                
            elseif app.DepositEditField.Visible == "on"
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.CloseAccountButton.Visible = 1;
                app.TradingRecordButton.Visible = 1;
                app.ReturnBackButton.Visible = 1;
                app.Money.Visible = 0;
                app.MoneyLabel.Visible = 0;
                app.Money.Value = 0;
                app.DepositEditFieldLabel.Visible = 0;
                app.DepositEditField.Visible = 0;
                app.ConfirmButton_4.Visible = 0;
                
            elseif app.WithdrawEditField.Visible == "on"
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.CloseAccountButton.Visible = 1;
                app.TradingRecordButton.Visible = 1;
                app.ReturnBackButton.Visible = 1;
                app.Money.Visible = 0;
                app.MoneyLabel.Visible = 0;
                app.Money.Value = 0;
                app.WithdrawEditField.Visible = 0;
                app.WithdrawEditFieldLabel.Visible = 0;
                app.ConfirmButton_3.Visible = 0;
                
            elseif app.TextArea_2.Visible == "on"
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.CloseAccountButton.Visible = 1;
                app.TradingRecordButton.Visible = 1;
                app.ReturnBackButton.Visible = 1;
                app.TextArea.Visible = 1;
                app.TextArea_2.Visible = 0;
                app.TextArea_2.Value = "";
                
            elseif app.ClosingAccountEditField.Visible == "on"
                app.TextArea.Value = "Choose Your Business";
                app.AccountEditFieldLabel.Visible = 0;
                app.AccountEditField.Visible = 0;
                app.PasswordEditFieldLabel.Visible = 0;
                app.PasswordEditField.Visible = 0;
                app.LoginButton.Visible = 0;
                app.FinishButton.Position = [257,63,131,40];
                app.CheckBalanceButton.Visible = 1;
                app.TransferButton.Visible = 1;
                app.WithdrawButton.Visible = 1;
                app.DepositButton.Visible = 1;
                app.CloseAccountButton.Visible = 1;
                app.TradingRecordButton.Visible = 1;
                app.ReturnBackButton.Visible = 1;
                app.Money.Visible = 0;
                app.MoneyLabel.Visible = 0;
                app.Money.Value = 0;
                app.ClosingAccountEditField.Visible = 0;
                app.ClosingAccountEditFieldLabel.Visible = 0;
                app.YesButton.Visible = 0;
                
            elseif app.CheckBalanceButton.Visible == "on"
                app.AccountEditFieldLabel.Visible = 1;
                app.AccountEditField.Visible = 1;
                app.PasswordEditFieldLabel.Visible = 1;
                app.PasswordEditField.Visible = 1;
                app.AccountEditField.Value = "";
                app.PasswordEditField.Value = "";
                app.LoginButton.Visible = 1;
                app.FinishButton.Position = [255,165,131,40];
                app.CheckBalanceButton.Visible = 0;
                app.TransferButton.Visible = 0;
                app.WithdrawButton.Visible = 0;
                app.DepositButton.Visible = 0;
                app.CloseAccountButton.Visible = 0;
                app.TradingRecordButton.Visible = 0;
                app.YourNameLabel.Text = "";
                app.YourNameLabel.Visible = 0;
                app.TextArea.Value = "Please Insert Your Card and Fill In Password";
            end
        end

        % Button pushed function: CheckBalanceButton
        function CheckBalanceButtonPushed(app, event)
            app.AccountEditFieldLabel.Visible = 0;
            app.AccountEditField.Visible = 0;
            app.PasswordEditFieldLabel.Visible = 0;
            app.PasswordEditField.Visible = 0;
            app.AccountEditField.Value = "";
            app.PasswordEditField.Value = "";
            app.LoginButton.Visible = 0;
            app.FinishButton.Position = [255,165,131,40];
            app.CheckBalanceButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.CloseAccountButton.Visible = 0;
            app.TradingRecordButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.Money.Visible = 1;
            app.MoneyLabel.Visible = 1;
            app.TextArea.Value = "Money On Your Account";
            base = importfile(app.DB.addr);
            app.Money.Value = base.Balance(app.Cur_ind);
        end

        % Button pushed function: TransferButton
        function TransferButtonPushed(app, event)
            app.AccountEditFieldLabel.Visible = 0;
            app.AccountEditField.Visible = 0;
            app.PasswordEditFieldLabel.Visible = 0;
            app.PasswordEditField.Visible = 0;
            app.AccountEditField.Value = "";
            app.PasswordEditField.Value = "";
            app.LoginButton.Visible = 0;
            app.FinishButton.Position = [255,165,131,40];
            app.CheckBalanceButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.CloseAccountButton.Visible = 0;
            app.TradingRecordButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.TextArea.Value = "Transfer";
            app.FromEditField.Visible = 1;
            app.FromEditFieldLabel.Visible = 1;
            app.ToEditField.Visible = 1;
            app.ToEditFieldLabel.Visible = 1;
            app.AmountEditField.Visible = 1;
            app.AmountEditFieldLabel.Visible = 1;
            app.ConfirmButton_2.Visible = 1;
            
            base = importfile(app.DB.addr);
            app.FromEditField.Value = base.Account(app.Cur_ind);            
        end

        % Button pushed function: WithdrawButton
        function WithdrawButtonPushed(app, event)
            app.AccountEditFieldLabel.Visible = 0;
            app.AccountEditField.Visible = 0;
            app.PasswordEditFieldLabel.Visible = 0;
            app.PasswordEditField.Visible = 0;
            app.AccountEditField.Value = "";
            app.PasswordEditField.Value = "";
            app.LoginButton.Visible = 0;
            app.FinishButton.Position = [255,165,131,40];
            app.CheckBalanceButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.CloseAccountButton.Visible = 0;
            app.TradingRecordButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.TextArea.Value = "Withdraw";
            app.WithdrawEditField.Visible = 1;
            app.WithdrawEditFieldLabel.Visible = 1;
            app.ConfirmButton_3.Visible = 1;
        end

        % Button pushed function: DepositButton
        function DepositButtonPushed(app, event)
            app.AccountEditFieldLabel.Visible = 0;
            app.AccountEditField.Visible = 0;
            app.PasswordEditFieldLabel.Visible = 0;
            app.PasswordEditField.Visible = 0;
            app.AccountEditField.Value = "";
            app.PasswordEditField.Value = "";
            app.LoginButton.Visible = 0;
            app.FinishButton.Position = [255,165,131,40];
            app.CheckBalanceButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.CloseAccountButton.Visible = 0;
            app.TradingRecordButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.TextArea.Value = "Deposit";
            app.DepositEditFieldLabel.Visible = 1;
            app.DepositEditField.Visible = 1;
            app.ConfirmButton_4.Visible = 1;
        end

        % Button pushed function: CloseAccountButton
        function CloseAccountButtonPushed(app, event)
            app.AccountEditFieldLabel.Visible = 0;
            app.AccountEditField.Visible = 0;
            app.PasswordEditFieldLabel.Visible = 0;
            app.PasswordEditField.Visible = 0;
            app.AccountEditField.Value = "";
            app.PasswordEditField.Value = "";
            app.LoginButton.Visible = 0;
            app.FinishButton.Position = [255,165,131,40];
            app.CheckBalanceButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.CloseAccountButton.Visible = 0;
            app.TradingRecordButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            app.TextArea.Value = "You Are Trying To Close This Account";
            app.ClosingAccountEditField.Visible = 1;
            app.ClosingAccountEditFieldLabel.Visible = 1;
            app.YesButton.Visible = 1;
            
            base = importfile(app.DB.addr);
            app.ClosingAccountEditField.Value = base.Account(app.Cur_ind);
        end

        % Button pushed function: ConfirmButton_2
        function ConfirmButton_2Pushed(app, event)
            accto = app.ToEditField.Value;
            base = importfile(app.DB.addr);
            acclist = base.Account;
            if app.TextArea.Value == "Please Confirm Again"
                base.Balance(app.To_ind) = base.Balance(app.To_ind) + app.AmountEditField.Value;
                base.Balance(app.Cur_ind) = base.Balance(app.Cur_ind) - app.AmountEditField.Value;
                app.DB.rewrite(base);
                app.TextArea.Value = "Successful!";
                app.AmountEditField.Value = 0;
                app.ToEditField.Value = 0;
            elseif find(acclist == accto)
                app.To_ind = find(acclist == accto);
                if app.AmountEditField.Value <= 0
                    app.TextArea.Value = "Invalid Amount";
                else
                    balan = base.Balance(app.Cur_ind);
                    if (balan >= app.AmountEditField.Value)
                        app.TextArea.Value = "Please Confirm Again";
                    else
                        app.TextArea.Value = "Not Enough Balance";
                    end
                end
            else
                app.TextArea.Value = "No Such Account";
            end
            
        end

        % Button pushed function: ConfirmButton_3
        function ConfirmButton_3Pushed(app, event)
            base = importfile(app.DB.addr);
            if app.TextArea.Value == "Please Confirm Again"
                base.Balance(app.Cur_ind) = base.Balance(app.Cur_ind) - app.WithdrawEditField.Value;
                app.DB.rewrite(base);
                app.TextArea.Value = "Successful!";
                app.WithdrawEditField.Value = 0;
            elseif app.WithdrawEditField.Value > 0
                balan = base.Balance(app.Cur_ind);
                if (balan >= app.WithdrawEditField.Value)
                    app.TextArea.Value = "Please Confirm Again";
                else
                    app.TextArea.Value = "Not Enough Balance";
                end
            else
                app.TextArea.Value = "Invalid Amount";
            end
       
        end

        % Button pushed function: ConfirmButton_4
        function ConfirmButton_4Pushed(app, event)
            base = importfile(app.DB.addr);
            if app.TextArea.Value == "Please Confirm Again"
                base.Balance(app.Cur_ind) = base.Balance(app.Cur_ind) + app.DepositEditField.Value;
                app.DB.rewrite(base);
                app.TextArea.Value = "Successful!";
                app.DepositEditField.Value = 0;
            elseif app.DepositEditField.Value > 0
                app.TextArea.Value = "Please Confirm Again";
            else
                app.TextArea.Value = "Invalid Amount";
            end
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            base = importfile(app.DB.addr);
            if app.TextArea.Value == "Are You Sure?"
                base(app.Cur_ind,:) = [];
                app.DB.rewrite(base);
                app.TextArea.Value = "Account Closed";
                app.ReturnBackButton.Visible = 0;
                app.YesButton.Text = "Quit";
            elseif app.TextArea.Value == "Account Closed"
                closereq;
            else
                app.TextArea.Value = "Are You Sure?";
            end
        end

        % Button pushed function: TradingRecordButton
        function TradingRecordButtonPushed(app, event)
            app.AccountEditFieldLabel.Visible = 0;
            app.AccountEditField.Visible = 0;
            app.PasswordEditFieldLabel.Visible = 0;
            app.PasswordEditField.Visible = 0;
            app.AccountEditField.Value = "";
            app.PasswordEditField.Value = "";
            app.LoginButton.Visible = 0;
            app.FinishButton.Position = [255,165,131,40];
            app.CheckBalanceButton.Visible = 0;
            app.TransferButton.Visible = 0;
            app.WithdrawButton.Visible = 0;
            app.DepositButton.Visible = 0;
            app.CloseAccountButton.Visible = 0;
            app.ReturnBackButton.Visible = 1;
            
            app.TradingRecordButton.Visible = 0;
            app.TextArea.Visible = 0;
            app.TextArea_2.Visible = 1;
            app.TextArea_2.Value = "Under Construction";
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Cashier = uifigure;
            app.Cashier.Name = 'Cashier';
            app.CurrentNOEditFieldLabel = uilabel(app.Cashier);
            app.CurrentNOEditFieldLabel.Text = 'Current NO';
            app.CurrentNOEditField = uieditfield(app.Cashier, 'numeric');
            app.CurrentNOEditField.Editable = 'off';
            app.TransferButton = uibutton(app.Cashier, 'push');
            app.TransferButton.ButtonPushedFcn = createCallbackFcn(app, @TransferButtonPushed, true);
            app.TransferButton.Visible = 'off';
            app.TransferButton.Text = 'Transfer';
            app.WithdrawButton = uibutton(app.Cashier, 'push');
            app.WithdrawButton.ButtonPushedFcn = createCallbackFcn(app, @WithdrawButtonPushed, true);
            app.WithdrawButton.Visible = 'off';
            app.WithdrawButton.Text = 'Withdraw';
            app.DepositButton = uibutton(app.Cashier, 'push');
            app.DepositButton.ButtonPushedFcn = createCallbackFcn(app, @DepositButtonPushed, true);
            app.DepositButton.Visible = 'off';
            app.DepositButton.Text = 'Deposit';
            app.CloseAccountButton = uibutton(app.Cashier, 'push');
            app.CloseAccountButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAccountButtonPushed, true);
            app.CloseAccountButton.Visible = 'off';
            app.CloseAccountButton.Text = {'Close Account'; ''};
            app.ConfirmButton = uibutton(app.Cashier, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Visible = 'off';
            app.ConfirmButton.Text = 'Confirm';
            app.ReturnBackButton = uibutton(app.Cashier, 'push');
            app.ReturnBackButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnBackButtonPushed, true);
            app.ReturnBackButton.Visible = 'off';
            app.ReturnBackButton.Text = 'Return Back';
            app.OpenAccountButton = uibutton(app.Cashier, 'push');
            app.OpenAccountButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAccountButtonPushed, true);
            app.OpenAccountButton.Text = 'Open Account';
            app.CheckBalanceButton = uibutton(app.Cashier, 'push');
            app.CheckBalanceButton.ButtonPushedFcn = createCallbackFcn(app, @CheckBalanceButtonPushed, true);
            app.CheckBalanceButton.Visible = 'off';
            app.CheckBalanceButton.Text = 'Check Balance';
            app.LoginButton = uibutton(app.Cashier, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Visible = 'off';
            app.LoginButton.Text = 'Login';
            app.FinishButton = uibutton(app.Cashier, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Tooltip = {''; ''};
            app.FinishButton.Text = 'Finish';
            app.InsertCardButton = uibutton(app.Cashier, 'push');
            app.InsertCardButton.ButtonPushedFcn = createCallbackFcn(app, @InsertCardButtonPushed, true);
            app.InsertCardButton.Text = 'Insert Card';
            app.AccountEditFieldLabel = uilabel(app.Cashier);
            app.AccountEditFieldLabel.Visible = 'off';
            app.AccountEditFieldLabel.Text = 'Account';
            app.AccountEditField = uieditfield(app.Cashier, 'text');
            app.AccountEditField.Visible = 'off';
            app.PasswordEditFieldLabel = uilabel(app.Cashier);
            app.PasswordEditFieldLabel.Visible = 'off';
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.Cashier, 'text');
            app.PasswordEditField.Visible = 'off';
            app.YourAccountEditFieldLabel = uilabel(app.Cashier);
            app.YourAccountEditFieldLabel.Visible = 'off';
            app.YourAccountEditFieldLabel.Text = 'Your Account';
            app.YourAccountEditField = uieditfield(app.Cashier, 'text');
            app.YourAccountEditField.Editable = 'off';
            app.YourAccountEditField.Visible = 'off';
            app.SetPasswordEditFieldLabel = uilabel(app.Cashier);
            app.SetPasswordEditFieldLabel.Visible = 'off';
            app.SetPasswordEditFieldLabel.Text = 'Set Password';
            app.SetPasswordEditField = uieditfield(app.Cashier, 'text');
            app.SetPasswordEditField.Visible = 'off';
            app.TextArea = uitextarea(app.Cashier);
            app.TextArea.Editable = 'off';
            app.MoneyLabel = uilabel(app.Cashier);
            app.MoneyLabel.Visible = 'off';
            app.MoneyLabel.Text = 'Money: ¥';
            app.Money = uieditfield(app.Cashier, 'numeric');
            app.Money.Limits = [0 Inf];
            app.Money.ValueDisplayFormat = '%.2f';
            app.Money.Editable = 'off';
            app.Money.Visible = 'off';
            app.FromEditFieldLabel = uilabel(app.Cashier);
            app.FromEditFieldLabel.Visible = 'off';
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.Cashier, 'numeric');
            app.FromEditField.Limits = [0 Inf];
            app.FromEditField.ValueDisplayFormat = '%.0f';
            app.FromEditField.Editable = 'off';
            app.FromEditField.Visible = 'off';
            app.ToEditFieldLabel = uilabel(app.Cashier);
            app.ToEditFieldLabel.Visible = 'off';
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.Cashier, 'numeric');
            app.ToEditField.Limits = [0 999999];
            app.ToEditField.ValueDisplayFormat = '%.0f';
            app.ToEditField.Visible = 'off';
            app.AmountEditFieldLabel = uilabel(app.Cashier);
            app.AmountEditFieldLabel.Visible = 'off';
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.Cashier, 'numeric');
            app.AmountEditField.Limits = [0 Inf];
            app.AmountEditField.ValueDisplayFormat = '%.2f';
            app.AmountEditField.Visible = 'off';
            app.ConfirmButton_2 = uibutton(app.Cashier, 'push');
            app.ConfirmButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_2Pushed, true);
            app.ConfirmButton_2.Visible = 'off';
            app.ConfirmButton_2.Text = 'Confirm';
            app.WithdrawEditFieldLabel = uilabel(app.Cashier);
            app.WithdrawEditFieldLabel.Visible = 'off';
            app.WithdrawEditFieldLabel.Text = 'Withdraw  ¥';
            app.WithdrawEditField = uieditfield(app.Cashier, 'numeric');
            app.WithdrawEditField.Limits = [0 Inf];
            app.WithdrawEditField.ValueDisplayFormat = '%.2f';
            app.WithdrawEditField.Visible = 'off';
            app.ConfirmButton_3 = uibutton(app.Cashier, 'push');
            app.ConfirmButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_3Pushed, true);
            app.ConfirmButton_3.Visible = 'off';
            app.ConfirmButton_3.Text = 'Confirm';
            app.DepositEditFieldLabel = uilabel(app.Cashier);
            app.DepositEditFieldLabel.Visible = 'off';
            app.DepositEditFieldLabel.Text = 'Deposit  ¥';
            app.DepositEditField = uieditfield(app.Cashier, 'numeric');
            app.DepositEditField.Limits = [0 Inf];
            app.DepositEditField.ValueDisplayFormat = '%.2f';
            app.DepositEditField.Visible = 'off';
            app.ConfirmButton_4 = uibutton(app.Cashier, 'push');
            app.ConfirmButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButton_4Pushed, true);
            app.ConfirmButton_4.Visible = 'off';
            app.ConfirmButton_4.Text = 'Confirm';
            app.YesButton = uibutton(app.Cashier, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Visible = 'off';
            app.YesButton.Text = 'Yes';
            app.ClosingAccountEditFieldLabel = uilabel(app.Cashier);
            app.ClosingAccountEditFieldLabel.Visible = 'off';
            app.ClosingAccountEditFieldLabel.Text = 'Closing Account';
            app.ClosingAccountEditField = uieditfield(app.Cashier, 'numeric');
            app.ClosingAccountEditField.Limits = [0 999999];
            app.ClosingAccountEditField.ValueDisplayFormat = '%.0f';
            app.ClosingAccountEditField.Editable = 'off';
            app.ClosingAccountEditField.Visible = 'off';
            app.TradingRecordButton = uibutton(app.Cashier, 'push');
            app.TradingRecordButton.ButtonPushedFcn = createCallbackFcn(app, @TradingRecordButtonPushed, true);
            app.TradingRecordButton.Visible = 'off';
            app.TradingRecordButton.Text = 'Trading Record';
            app.TextArea_2 = uitextarea(app.Cashier);
            app.TextArea_2.Visible = 'off';
            app.YourNameEditFieldLabel = uilabel(app.Cashier);
            app.YourNameEditFieldLabel.Visible = 'off';
            app.YourNameEditFieldLabel.Text = 'Your Name';
            app.YourNameEditField = uieditfield(app.Cashier, 'text');
            app.YourNameEditField.Visible = 'off';
            app.YourNameLabel = uilabel(app.Cashier);
            app.YourNameLabel.Visible = 'off';
            app.YourNameLabel.Text = 'Your Name';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CashierUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Cashier)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Cashier)
        end
    end
end